classdef LFITv2_GUI_Prerun_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigGUI          matlab.ui.Figure
        tagHeaderIm         matlab.ui.control.UIAxes
        uipanel11           matlab.ui.container.Panel
        uipanel12           matlab.ui.container.Panel
        tagTextCal          matlab.ui.control.Label
        pushbutton9         matlab.ui.control.Button
        uipanel13           matlab.ui.container.Panel
        tagTextPlen         matlab.ui.control.Label
        pushbutton10        matlab.ui.control.Button
        uipanel15           matlab.ui.container.Panel
        tagTextOut          matlab.ui.control.Label
        pushbutton11        matlab.ui.control.Button
        uipanel19           matlab.ui.container.Panel
        uipanel20           matlab.ui.container.Panel
        tagMainFocalLen     matlab.ui.control.EditField
        text17              matlab.ui.control.Label
        text19              matlab.ui.control.Label
        tagMagnification    matlab.ui.control.Label
        tagCameraSelection  matlab.ui.container.ButtonGroup
        tagRect             matlab.ui.control.RadioButton
        tagHexa             matlab.ui.control.RadioButton
        tagOtherCamera      matlab.ui.control.RadioButton
        tagCalLoadSave      matlab.ui.container.ButtonGroup
        tagNoLoadSave       matlab.ui.control.RadioButton
        tagAutoLoadSave     matlab.ui.control.RadioButton
        tagClearCalSaveNew  matlab.ui.control.RadioButton
        tagLoadExternalCal  matlab.ui.control.RadioButton
        uipanel16           matlab.ui.container.Panel
        tagTextImageSet     matlab.ui.control.EditField
        uipanel17           matlab.ui.container.ButtonGroup
        tagRadioBatch       matlab.ui.control.RadioButton
        tagRadioSingleIm    matlab.ui.control.RadioButton
        uipanel21           matlab.ui.container.Panel
        tagRefHeight        matlab.ui.control.EditField
        text18              matlab.ui.control.Label
        text33              matlab.ui.control.Label
        tagNumThreads       matlab.ui.control.EditField
        pushbutton13        matlab.ui.control.Button
        pushbutton14        matlab.ui.control.Button
        tagSaveConfig       matlab.ui.control.Button
        tagLoadConfig       matlab.ui.control.Button
        pushbutton17        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function initialize_gui(app, hObject, handles, isreset)
            % If the calFolderPath field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
            if isfield(handles, 'calFolderPath') && ~isreset
                return;
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end
        
        function handles = loadState(app, pathname, filename, hObject, handles)
            try
                temp = load(fullfile(pathname,filename), '-mat');
            
                handles.cal_path            = temp.state.v01;
                handles.plen_path           = temp.state.v02;
                handles.out_path            = temp.state.v03;
                handles.imageSetName        = temp.state.v04;
                handles.runMode             = temp.state.v05;
                handles.focLenMain          = temp.state.v07;
                handles.pixelPitch          = temp.state.v08;
                handles.rulerHeight         = temp.state.v09;
                handles.focLenMicro         = temp.state.v10;
                handles.sensorHeight        = temp.state.v11;
                handles.magnification       = temp.state.v12;
                handles.microPitch          = temp.state.v13;
                handles.numMicroX           = temp.state.v14;
                handles.numMicroY           = temp.state.v15;
                handles.microDiameterExact  = temp.state.v16;
                handles.loadFlag            = temp.state.v17;
                handles.saveFlag            = temp.state.v18;
                handles.sizePixelAperture   = temp.state.v19;
                handles.sensorType          = temp.state.v20;
                handles.numThreads          = temp.state.v21;
            
                set(handles.tagTextCal, 'String', handles.cal_path);
                set(handles.tagTextPlen, 'String', handles.plen_path);
                set(handles.tagTextOut, 'String', handles.out_path);
                set(handles.tagTextImageSet, 'String', handles.imageSetName);
                if handles.runMode == 0
                    set(handles.tagRadioSingleIm, 'Value', 1);
                else
                    set(handles.tagRadioBatch, 'Value', 1);
                end
            
                set(handles.tagMainFocalLen, 'String', handles.focLenMain);
            
                set(handles.tagRefHeight, 'String', handles.rulerHeight);
            
                set(handles.tagMagnification, 'String', handles.magnification);
                if handles.loadFlag == 0 && handles.saveFlag == 0
                    set(handles.tagNoLoadSave, 'Value', 1);
                else
                    set(handles.tagAutoLoadSave, 'Value', 1);
                end
                % Note that no provision is made to remember if clear calibration was selected. This is by design to prevent accidental deletion of calibration data.
            
                if handles.loadFlag == 3 %load external cal
                    set(handles.tagLoadExternalCal, 'Value',1);
                end
            
                if handles.sensorType == 'rect'
                    set(handles.tagRect, 'Value', 1);
                else
                    set(handles.tagHexa, 'Value', 1);
                end
            
            catch generror2
                %load failed
                warning('Loading of previous configuration settings failed. Resetting to default values...');
                handles = setDefaults(app, handles);
            end
            % Update handles structure
            guidata(hObject, handles);
        end
        
        function saveState(app, pathname, filename, handles)
            
            state.v01 = handles.cal_path;
            state.v02 = handles.plen_path;
            state.v03 = handles.out_path;
            state.v04 = handles.imageSetName;
            state.v05 = handles.runMode;
            state.v07 = handles.focLenMain;
            state.v08 = handles.pixelPitch;
            state.v09 = handles.rulerHeight;
            state.v10 = handles.focLenMicro;
            state.v11 = handles.sensorHeight;
            state.v12 = handles.magnification;
            state.v13 = handles.microPitch;
            state.v14 = handles.numMicroX;
            state.v15 = handles.numMicroY;
            state.v16 = handles.microDiameterExact;
            state.v17 = handles.loadFlag;
            state.v18 = handles.saveFlag;
            state.v19 = handles.sizePixelAperture;
            state.v20 = handles.sensorType;
            state.v21 = handles.numThreads;
            
            save(fullfile(pathname,filename), 'state','-mat');
        end
        
        function handles = setDefaults(app, hObject, handles)
            % Put default values into handles structure
            handles.cal_path        = 'C:\TestFolder\Calibration';
            handles.plen_path       = 'C:\TestFolder\Images';
            handles.out_path        =  fullfile(handles.plen_path,'Output');
            handles.imageSetName    = 'Test';
            handles.runMode         = 0; %0 = single, 1 = batch
            handles.focLenMain      = 50;
            % handles.focLenMain      = 80;
            handles.pixelPitch      = 0.0055;
            % handles.pixelPitch      = 0.0074;
            handles.rulerHeight     = 24.2;
            % handles.rulerHeight     = 406;
            handles.focLenMicro     = 0.308;
            % handles.focLenMicro     = 0.5;
            handles.sensorHeight    = 24.272;   % mm
            handles.magnification   = -handles.sensorHeight/handles.rulerHeight;
            handles.microPitch      = 0.077;	% mm
            % handles.microPitch      = 0.125;	% mm
            
            handles.numMicroX           = 471;      % mm
            handles.numMicroY           = 362;      % mm
            handles.microDiameterExact  = 14.0127;  % pixels
            handles.loadFlag            = 1;
            handles.saveFlag            = 1;
            handles.startProgram        = false;
            handles.sensorType          = 'hexa';   % 'rect' or 'hexa' (string)
            
            handles.numThreads          = 4;
            
            set(handles.tagTextCal, 'String', handles.cal_path);
            set(handles.tagTextPlen, 'String', handles.plen_path);
            set(handles.tagTextOut, 'String', handles.out_path);
            set(handles.tagTextImageSet, 'String', handles.imageSetName);
            if handles.runMode == 0
                set(handles.tagRadioSingleIm, 'Value', 1);
            else
                set(handles.tagRadioBatch, 'Value', 1);
            end
            
            set(handles.tagMainFocalLen, 'String', handles.focLenMain);
            
            set(handles.tagRefHeight, 'String', handles.rulerHeight);
            
            set(handles.tagMagnification, 'String', handles.magnification);
            
            if handles.loadFlag == 0 && handles.saveFlag == 0
                set(handles.tagNoLoadSave, 'Value', 1);
            else
                set(handles.tagAutoLoadSave, 'Value', 1);
            end
            
            si = handles.focLenMain*(1-(handles.magnification));
            sizePixelAperture = (si*handles.pixelPitch)/handles.focLenMicro;
            handles.sizePixelAperture = sizePixelAperture;
            
            if strcmp(handles.sensorType,'rect') == true
                set(handles.tagRect, 'Value', 1); %rect
            else
                set(handles.tagHexa, 'Value', 1); %hexa
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LFITv2_GUI_Prerun_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to LFITv2_GUI_Prerun (see VARARGIN)
            
            handles = setDefaults(app, hObject,handles);
            
            % Load last run if present
            if ~isempty(dir('lastrun.cfg')) %better than using 'exist', which looks on the entire search path.
                handles = loadState(app, cd,'lastrun.cfg',hObject,handles);
            end
            
            % Choose default command line output for LFITv2_GUI_Prerun
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Update main title axes
            axes(handles.tagHeaderIm);
            imshow('header.png');
            
            initialize_gui(app, hObject, handles, false);
            
            % UIWAIT makes LFITv2_GUI_Prerun wait for user response (see UIRESUME)
            uiwait(hObject);
        end

        % Close request function: mainFigGUI
        function mainFigGUI_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mainFigGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isequal(get(hObject, 'waitstatus'), 'waiting')
                % The GUI is still in UIWAIT, use UIRESUME and return
                uiresume(hObject);
            else
                % The GUI is no longer waiting, so destroy it now.
                delete(hObject);
            end
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.plen_path ~=  0
                directory_name = uigetdir(handles.plen_path,'Open folder of raw TIFF plenoptic images for processing...');
                if directory_name ~= 0
                    handles.plen_path = directory_name;
                    set(handles.tagTextPlen,'String',directory_name);
                else
                    set(handles.tagTextPlen,'String',handles.plen_path);
                end
            else
                directory_name = uigetdir(path,'Open folder of raw TIFF plenoptic images for processing...');
                if directory_name ~= 0
                    handles.plen_path = directory_name;
                    set(handles.tagTextPlen,'String',directory_name);
                else
                    set(handles.tagTextPlen,'String',handles.plen_path);
                end
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.out_path ~=  0
                directory_name = uigetdir(handles.plen_path,'Select an output folder to hold all exported/processed images...');
                if directory_name ~= 0
                    handles.out_path = directory_name;
                    set(handles.tagTextOut,'String',directory_name);
                else
                    set(handles.tagTextOut,'String',handles.out_path);
                end
            else
                directory_name = uigetdir(path,'Select an output folder to hold all exported/processed images...');
                if directory_name ~= 0
                    handles.out_path = directory_name;
                    set(handles.tagTextOut,'String',directory_name);
                else
                    set(handles.tagTextOut,'String',handles.out_path);
                end
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton13
        function pushbutton13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Close button not pressed; run button pressed, so let program continue
            handles.startProgram = true;
            
            % Update handles structure
            guidata(hObject, handles);
            
            uiresume(handles.mainFigGUI);
            
            % Get parallel pool status
            p = gcp('nocreate');
            if isempty(p)
                parpool( handles.numThreads );      % Create new pool
            elseif p.NumWorkers ~= handles.numThreads
                delete(p);                          % Destroy existing pool
                parpool( handles.numThreads );      % Create new pool
            else
                % Pool of appropriate size already exists
            end
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open('LFITv2_Documentation.pdf');
        end

        % Button pushed function: pushbutton17
        function pushbutton17_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            web mailto:mzm0210@auburn.edu
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.cal_path ~=  0
                directory_name = uigetdir(handles.cal_path,'Select the folder containing the raw TIFF calibration images...');
                if directory_name ~=  0 % if the user didn't pick a folder
                    handles.cal_path = directory_name;
                    set(handles.tagTextCal,'String',directory_name); % update GUI/set to new value
                else
                    set(handles.tagTextCal,'String',handles.cal_path); %set to old value
                end
            else
                directory_name = uigetdir(path,'Select the folder containing the raw TIFF calibration images...');
                if directory_name ~=  0 % if the user didn't pick a folder
                    handles.cal_path = directory_name;
                    set(handles.tagTextCal,'String',directory_name); % update GUI/set to new value
                else
                    set(handles.tagTextCal,'String',handles.cal_path); %set to old value
                end
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Selection changed function: tagCalLoadSave
        function tagCalLoadSave_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in tagCalLoadSave
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'tagNoLoadSave'
                    handles.loadFlag = 0;
                    handles.saveFlag = 0;
                case 'tagAutoLoadSave'
                    handles.loadFlag = 1;
                    handles.saveFlag = 1;
                case 'tagClearCalSaveNew'
                    handles.loadFlag = 2;
                    handles.saveFlag = 1;
                case 'tagLoadExternalCal'
                    handles.loadFlag = 3;
                    handles.saveFlag = 0;
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Selection changed function: tagCameraSelection
        function tagCameraSelection_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in tagCameraSelection
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            % CAMERA DATA DEFINED HERE
            % To change camera data, replace the data in this section.
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
            
                % 16 MP (Rectangular Microlens Array) Camera
                case 'tagRect'
                    handles.sensorType          = 'rect';
                    handles.pixelPitch          = 0.0074;   % mm
                    handles.focLenMicro         = 0.5;      % mm
                    handles.sensorHeight        = 24.272;   % mm
            
                    % Not visualized in GUI
                    handles.microPitch          = 0.125;    % mm
                    handles.numMicroX           = 289;
                    handles.numMicroY           = 193;
                    handles.microDiameterExact  = 16.9689;  % pixels
            
                % 29 MP (Hexagonal Microlens Array) Camera
                case 'tagHexa'
                    handles.sensorType          = 'hexa';
                    handles.pixelPitch          = 0.0055;	% mm
                    handles.focLenMicro         = 0.308;	% mm
                    handles.sensorHeight        = 24.272;   % mm
            
                    % Not visualized in GUI
                    handles.microPitch          = 0.077;	% mm
                    handles.numMicroX           = 471;
                    handles.numMicroY           = 362;
                    handles.microDiameterExact  = 14.0127;  % pixels
            
                 % Other User Defined Camera
                case 'tagOtherCamera'
                    handles.sensorType          = input('Please type the arrangement of the microlens array:''hexa'' or ''rect''\n');
                    handles.pixelPitch          = input('Please type the pixel pitch in mm\n');
                    handles.focLenMicro         = input('Please type the microlens focal length in mm\n');
                    handles.sensorHeight        = input('Please type the image sensor height in mm\n');
                    handles.microPitch          = input('Please type the microlens pitch in mm\n');
                    handles.numMicroX           = input('Please type the number of microlens in the horizontal direction\n');
                    handles.numMicroY           = input('Please type the number of microlens in the vertical direction\n');
                    handles.microDiameterExact  = input('Please type the diameter of a microlens in pixels\n');
            
            end
            
            handles.magnification = -handles.sensorHeight/handles.rulerHeight;
            set(handles.tagMagnification, 'String', handles.magnification);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: tagLoadConfig
        function tagLoadConfig_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagLoadConfig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uigetfile({'*.cfg','Configuration Files (*.cfg)';}, 'Select a configuration file to load...');
            if filename ~= 0
                handles = loadState(app, pathname,filename,hObject,handles);
            end
        end

        % Value changed function: tagMainFocalLen
        function tagMainFocalLen_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagMainFocalLen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of tagMainFocalLen as text
            %        str2double(get(hObject,'String')) returns contents of tagMainFocalLen as a double
            input = str2double(get(hObject,'String'));
            handles.focLenMain = input;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: tagNumThreads
        function tagNumThreads_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagNumThreads (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of tagNumThreads as text
            %        str2double(get(hObject,'String')) returns contents of tagNumThreads as a double
            input = str2double(get(hObject,'String'));
            handles.numThreads = input;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: tagRefHeight
        function tagRefHeight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagRefHeight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of tagRefHeight as text
            %        str2double(get(hObject,'String')) returns contents of tagRefHeight as a double
            input = str2double(get(hObject,'String'));
            handles.rulerHeight = input;
            handles.magnification = -handles.sensorHeight/handles.rulerHeight;
            set(handles.tagMagnification, 'String', handles.magnification);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: tagSaveConfig
        function tagSaveConfig_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagSaveConfig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile( {'*.cfg','Configuration Files (*.cfg)';}, 'Save');
            if filename ~= 0
                saveState(app, pathname,filename,handles)
            end
        end

        % Value changed function: tagTextImageSet
        function tagTextImageSet_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tagTextImageSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of tagTextImageSet as text
            %        str2double(get(hObject,'String')) returns contents of tagTextImageSet as a double
            
            input = get(hObject,'String');
            handles.imageSetName = input;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Selection changed function: uipanel17
        function uipanel17_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uipanel17
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'tagRadioSingleIm'
                    handles.runMode = 0;
                case 'tagRadioBatch'
                    handles.runMode = 1;
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigGUI = uifigure('Visible', 'off');
            app.mainFigGUI.Name = 'LFITv2 | Pre-Processing Configuration Panel';
            app.mainFigGUI.CloseRequestFcn = createCallbackFcn(app, @mainFigGUI_CloseRequestFcn, true);
            app.mainFigGUI.Tag = 'mainFigGUI';
            app.tagHeaderIm = uiaxes(app.mainFigGUI);
            app.tagHeaderIm.XLim = [0.5 1500.5];
            app.tagHeaderIm.YLim = [0.5 123.5];
            app.tagHeaderIm.Visible = 'off';
            app.tagHeaderIm.Tag = 'tagHeaderIm';
            app.uipanel11 = uipanel(app.mainFigGUI);
            app.uipanel11.Title = 'Load Files';
            app.uipanel11.Tag = 'uipanel11';
            app.uipanel12 = uipanel(app.uipanel11);
            app.uipanel12.Title = 'Calibration Images Folder';
            app.uipanel12.Tag = 'uipanel12';
            app.tagTextCal = uilabel(app.uipanel12);
            app.tagTextCal.Tag = 'tagTextCal';
            app.tagTextCal.Text = '<directory UNDEFINED>';
            app.pushbutton9 = uibutton(app.uipanel11, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Tooltip = 'Open the folder containing the ~100 calibration images for a given experimental setup...';
            app.pushbutton9.Text = 'Open';
            app.uipanel13 = uipanel(app.uipanel11);
            app.uipanel13.Title = 'Plenoptic Images Folder';
            app.uipanel13.Tag = 'uipanel13';
            app.tagTextPlen = uilabel(app.uipanel13);
            app.tagTextPlen.Tag = 'tagTextPlen';
            app.tagTextPlen.Text = '<directory UNDEFINED>';
            app.pushbutton10 = uibutton(app.uipanel11, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Tooltip = 'Open the folder containing the raw plenoptic TIFF image(s) to be processed...';
            app.pushbutton10.Text = 'Open';
            app.uipanel15 = uipanel(app.uipanel11);
            app.uipanel15.Title = 'Output Folder';
            app.uipanel15.Tag = 'uipanel15';
            app.tagTextOut = uilabel(app.uipanel15);
            app.tagTextOut.Tag = 'tagTextOut';
            app.tagTextOut.Text = '<directory UNDEFINED>';
            app.pushbutton11 = uibutton(app.uipanel11, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Tooltip = 'Select a folder to store any exported images/videos...';
            app.pushbutton11.Text = 'Open';
            app.uipanel19 = uipanel(app.mainFigGUI);
            app.uipanel19.Title = 'Setup Parameters';
            app.uipanel19.Tag = 'uipanel19';
            app.uipanel20 = uipanel(app.uipanel19);
            app.uipanel20.Title = 'Main Lens Focal Length';
            app.uipanel20.Tag = 'uipanel20';
            app.tagMainFocalLen = uieditfield(app.uipanel20, 'text');
            app.tagMainFocalLen.ValueChangedFcn = createCallbackFcn(app, @tagMainFocalLen_Callback, true);
            app.tagMainFocalLen.Tag = 'tagMainFocalLen';
            app.tagMainFocalLen.Tooltip = 'Enter the focal length of the main lens in mm (e.g. 50)...';
            app.tagMainFocalLen.Value = '100';
            app.text17 = uilabel(app.uipanel20);
            app.text17.Tag = 'text17';
            app.text17.Text = 'millimeters';
            app.text19 = uilabel(app.uipanel19);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Magnification:';
            app.tagMagnification = uilabel(app.uipanel19);
            app.tagMagnification.Tag = 'tagMagnification';
            app.tagMagnification.Tooltip = 'The calculated magnification for the scene based on the sensor height (24.272 mm) divided by the reference/ruler height.';
            app.tagMagnification.Text = '0.40453';
            app.tagCameraSelection = uibuttongroup(app.uipanel19);
            app.tagCameraSelection.SelectionChangedFcn = createCallbackFcn(app, @tagCameraSelection_SelectionChangeFcn, true);
            app.tagCameraSelection.Title = 'Microlens Array Layout / Camera';
            app.tagCameraSelection.Tag = 'tagCameraSelection';
            app.tagRect = uiradiobutton(app.tagCameraSelection);
            app.tagRect.Tag = 'tagRect';
            app.tagRect.Tooltip = 'Imperx Bobcat ICL - B4820 (~288x194 microlenses)';
            app.tagRect.Text = '16 MP Imperx (Rectangular)';
            app.tagRect.Value = true;
            app.tagHexa = uiradiobutton(app.tagCameraSelection);
            app.tagHexa.Tag = 'tagHexa';
            app.tagHexa.Tooltip = 'Imperx Bobcat B6620 - (~471x362 microlenses)';
            app.tagHexa.Text = '29 MP Imperx (Hexagonal)';
            app.tagOtherCamera = uiradiobutton(app.tagCameraSelection);
            app.tagOtherCamera.Tag = 'tagOtherCamera';
            app.tagOtherCamera.Text = 'Other Camera';
            app.tagCalLoadSave = uibuttongroup(app.uipanel19);
            app.tagCalLoadSave.SelectionChangedFcn = createCallbackFcn(app, @tagCalLoadSave_SelectionChangeFcn, true);
            app.tagCalLoadSave.Title = 'Calibration Data';
            app.tagCalLoadSave.Tag = 'tagCalLoadSave';
            app.tagNoLoadSave = uiradiobutton(app.tagCalLoadSave);
            app.tagNoLoadSave.Tag = 'tagNoLoadSave';
            app.tagNoLoadSave.Tooltip = 'Do not load or save calibration data. Recalculate calibration every time.';
            app.tagNoLoadSave.Text = 'No Load/Save';
            app.tagNoLoadSave.Value = true;
            app.tagAutoLoadSave = uiradiobutton(app.tagCalLoadSave);
            app.tagAutoLoadSave.Tag = 'tagAutoLoadSave';
            app.tagAutoLoadSave.Tooltip = 'Program will try to load calibration data associated with the below Image Set Name; otherwise, it recomputes the calibration and saves it with the Image Set Name.';
            app.tagAutoLoadSave.Text = 'Auto Load/Save Mode';
            app.tagClearCalSaveNew = uiradiobutton(app.tagCalLoadSave);
            app.tagClearCalSaveNew.Tag = 'tagClearCalSaveNew';
            app.tagClearCalSaveNew.Tooltip = 'Program will delete any existing calibration data matching the Image Set Name, then recompute and save the new calibration data with the Image Set Name.';
            app.tagClearCalSaveNew.Text = 'Clear Old Calibration and Save Fresh Calibration';
            app.tagLoadExternalCal = uiradiobutton(app.tagCalLoadSave);
            app.tagLoadExternalCal.Tag = 'tagLoadExternalCal';
            app.tagLoadExternalCal.Tooltip = 'Load external calibration points from file (often used with synthetic imagery).';
            app.tagLoadExternalCal.Text = 'Load External Calibration Points From File';
            app.uipanel16 = uipanel(app.uipanel19);
            app.uipanel16.Title = 'Image Set Name';
            app.uipanel16.Tag = 'uipanel16';
            app.tagTextImageSet = uieditfield(app.uipanel16, 'text');
            app.tagTextImageSet.ValueChangedFcn = createCallbackFcn(app, @tagTextImageSet_Callback, true);
            app.tagTextImageSet.Tag = 'tagTextImageSet';
            app.tagTextImageSet.Tooltip = 'Name the set of images. This name is used to save/load calibration data and as a prefix on all exported images.';
            app.tagTextImageSet.Value = 'LabScenes';
            app.uipanel17 = uibuttongroup(app.uipanel19);
            app.uipanel17.SelectionChangedFcn = createCallbackFcn(app, @uipanel17_SelectionChangeFcn, true);
            app.uipanel17.Title = 'Run Mode';
            app.uipanel17.Tag = 'uipanel17';
            app.tagRadioBatch = uiradiobutton(app.uipanel17);
            app.tagRadioBatch.Tag = 'tagRadioBatch';
            app.tagRadioBatch.Tooltip = 'Select this to process an entire folder of images in accordance with the script file.';
            app.tagRadioBatch.Text = 'Batch Mode (Script)';
            app.tagRadioBatch.Value = true;
            app.tagRadioSingleIm = uiradiobutton(app.uipanel17);
            app.tagRadioSingleIm.Tag = 'tagRadioSingleIm';
            app.tagRadioSingleIm.Tooltip = 'Select this to process one image at a time with an interactive interface...';
            app.tagRadioSingleIm.Text = 'Single Image (GUI)';
            app.uipanel21 = uipanel(app.uipanel19);
            app.uipanel21.Title = 'Reference ("Ruler") Height';
            app.uipanel21.Tag = 'uipanel21';
            app.tagRefHeight = uieditfield(app.uipanel21, 'text');
            app.tagRefHeight.ValueChangedFcn = createCallbackFcn(app, @tagRefHeight_Callback, true);
            app.tagRefHeight.Tag = 'tagRefHeight';
            app.tagRefHeight.Tooltip = 'Take an image of a ruler filling the entire frame then enter the visible length in mm here...';
            app.tagRefHeight.Value = '60';
            app.text18 = uilabel(app.uipanel21);
            app.text18.Tag = 'text18';
            app.text18.Text = 'millimeters';
            app.text33 = uilabel(app.uipanel19);
            app.text33.Tag = 'text33';
            app.text33.Text = {'Number of'; 'Threads:'; ''};
            app.tagNumThreads = uieditfield(app.uipanel19, 'text');
            app.tagNumThreads.ValueChangedFcn = createCallbackFcn(app, @tagNumThreads_Callback, true);
            app.tagNumThreads.Tag = 'tagNumThreads';
            app.tagNumThreads.Tooltip = 'Enter the number of  threads to use';
            app.tagNumThreads.Value = '4';
            app.pushbutton13 = uibutton(app.mainFigGUI, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13_Callback, true);
            app.pushbutton13.Tag = 'pushbutton13';
            app.pushbutton13.Tooltip = 'Press this to begin calibration and/or image processing...';
            app.pushbutton13.Text = 'Continue...';
            app.pushbutton14 = uibutton(app.mainFigGUI, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Tooltip = 'Open the general documentation in the default local PDF viewer...';
            app.pushbutton14.Text = 'View Documentation';
            app.tagSaveConfig = uibutton(app.mainFigGUI, 'push');
            app.tagSaveConfig.ButtonPushedFcn = createCallbackFcn(app, @tagSaveConfig_Callback, true);
            app.tagSaveConfig.Tag = 'tagSaveConfig';
            app.tagSaveConfig.Tooltip = 'Save the values of the fields and selections in this interface to a file...';
            app.tagSaveConfig.Text = 'Save Settings...';
            app.tagLoadConfig = uibutton(app.mainFigGUI, 'push');
            app.tagLoadConfig.ButtonPushedFcn = createCallbackFcn(app, @tagLoadConfig_Callback, true);
            app.tagLoadConfig.Tag = 'tagLoadConfig';
            app.tagLoadConfig.Tooltip = 'Load a settings file for this specific interface panel...';
            app.tagLoadConfig.Text = 'Load Settings...';
            app.pushbutton17 = uibutton(app.mainFigGUI, 'push');
            app.pushbutton17.ButtonPushedFcn = createCallbackFcn(app, @pushbutton17_Callback, true);
            app.pushbutton17.Tag = 'pushbutton17';
            app.pushbutton17.Tooltip = 'Opens email program with a new email addressed to the developer contact...';
            app.pushbutton17.Text = 'Report Error';
            % Show the figure after all components are created
            app.mainFigGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LFITv2_GUI_Prerun_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)LFITv2_GUI_Prerun_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigGUI)
        end
    end
end